classdef PipetteForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DigitalIdentifierDropDownLabel  matlab.ui.control.Label
        DigitalIdentifierDropDown       matlab.ui.control.DropDown
        InternalDiameterUnitListBoxLabel  matlab.ui.control.Label
        InternalDiameterUnitListBox     matlab.ui.control.ListBox
        ExternalDiameterUnitListBox     matlab.ui.control.ListBox
        ExternalDiameterUnitListBoxLabel  matlab.ui.control.Label
        NoofElectrodesEditFieldLabel    matlab.ui.control.Label
        CompleteCheckBox                matlab.ui.control.CheckBox
        CancelButton                    matlab.ui.control.Button
        CreateButton                    matlab.ui.control.Button
        MaterialEditField               matlab.ui.control.EditField
        MaterialEditFieldLabel          matlab.ui.control.Label
        ManufacturerEditField           matlab.ui.control.EditField
        ManufaturerEditFieldLabel       matlab.ui.control.Label
        DigitalIdentifierEditField      matlab.ui.control.EditField
        DigitalIdentifierEditFieldLabel  matlab.ui.control.Label
        DescriptionEditField            matlab.ui.control.EditField
        DescriptionEditFieldLabel       matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        ExternalDiameterEditField       matlab.ui.control.EditField
        ExternalDiameterEditFieldLabel  matlab.ui.control.Label
        InternalDiameterEditField       matlab.ui.control.EditField
        InternalDiameterEditFieldLabel  matlab.ui.control.Label
        PIPETTELabel                    matlab.ui.control.Label
    end

         
    properties (Dependent)
        Visible (1,1) matlab.lang.OnOffSwitchState
    end

    properties
        % Whether the user confirms (saves changes) or cancels
        FinishState (1,1) string % "Save" or "Cancel"
        Pipette
    end

    properties (Access = private)
        IsStandalone (1,1) logical = true % If app is opened independently or from another app (i.e NDI Dataset Uploader)
        Manufacturer
    end

    methods 
        function set.Visible(app, value)
            app.UIFigure.Visible = value;
        end
        function value = get.Visible(app)
            value = app.UIFigure.Visible;
        end
    end

    methods (Access = public)
        % Open (external app) form where user can enter affiliation details
        function openManufacturerForm(app, manufacturerName)
        %openManufacturerForm Open Affiliation form where user can enter Affiliation details
            if ~isfield(app.UIForm, 'Manufacturer')
                app.UIForm.Manufacturer = ndi.database.metadata_app.Apps.AffiliationForm(); % Create the form
            else
                app.UIForm.Manufacturer.Visible = 'on'; % Make the form visible
            end

            if nargin > 1 && ~isempty(manufacturerName)
                app.UIForm.Manufacturer.setName(manufacturerName);
            end

            app.UIForm.Manufacturer.waitfor(); % Wait for user to proceed
            
            % Get user-inputs from form
            S = app.UIForm.Manufacturer.getAffiliationDetails();
           
            % Update data in table if user pressed save.
            mode = app.UIForm.Manufacturer.FinishState;
            if mode == "Save"
                app.ManufacturerEditField.Value = S.AffiliationName;
            end
            app.Manufacturer = S;

            app.UIForm.Manufacturer.reset()
            app.UIForm.Manufacturer.Visible = 'off'; % Hide the form (for later reuse)
        end

        function selected = digitalIdentifierSelected(app)
            if strcmp(app.DigitalIdentifierDropDown.Value, "Select type")
                selected = 0;
            else
                selected = 1;
            end
        end

        function entered = internalDiameterEntered(app)
            if isempty(app.InternalDiameterEditField.Value)
                entered = 0;
            else
                entered = 1;
            end
        end

        function entered = externalDiameterEntered(app)
            if isempty(app.ExternalDiameterEditField.Value)
                entered = 0;
            else
                entered = 1;
            end
        end
    
        function setProbeDetails(app, pipette)
            app.Pipette = pipette;
            app.NameEditField.Value = pipette.Name;
            app.DescriptionEditField.Value = pipette.Description;
            [type, value] = pipette.getDigitalIdentifier();
            app.DigitalIdentifierDropDown.Value = type;
            app.DigitalIdentifierEditField.Value = value;
            app.Manufacturer = pipette.Manufacturer;
            app.ManufacturerEditField.Value = pipette.getManufacturerName();

            app.CompleteCheckBox.Value = pipette.Complete;
            app.ExternalDiameterEditField.Value = pipette.ExternalDiameter;
            app.ExternalDiameterUnitListBox.Value = pipette.ExternalDiameterUnit;
            app.InternalDiameterEditField.Value = pipette.InternalDiameter;
            app.InternalDiameterUnitListBox.Value = pipette.InternalDiameterUnit;
            app.MaterialEditField.Value = pipette.Material;
        end

        function pipette = getProbeDetails(app)
            if isempty(app.Pipette)
                pipette = ndi.database.metadata_app.class.Pipette();
            else
                pipette = app.Pipette;
            end
            electrode.updateProperty("Name", app.NameEditField.Value);
            pipette.updateProperty("Description", app.DescriptionEditField.Value);
            digitalIdentifier = struct('Type', app.DigitalIdentifierDropDown.Value, 'Value', app.DigitalIdentifierEditField.Value);
            pipette.updateProperty("DigitalIdentifier", digitalIdentifier);
            pipette.updateProperty("Manufacturer", app.ManufacturerEditField.Value);
            pipette.updateProperty("Complete", app.CompleteCheckBox.Value);
            electrode.updateProperty("InternalDiameter", app.InternalDiameterEditField.Value);
            electrode.updateProperty("InternalDiameterUnit", app.InternalDiameterUnitListBox.Value);
            electrode.updateProperty("ExternalDiameter", app.ExternalDiameterEditField.Value);
            electrode.updateProperty("ExternalDiameterUnit", app.ExternalDiameterUnitListBox.Value);
            pipette.updateProperty("Material", app.MaterialEditField.Value);
        end

        function reset(app)
            app.NameEditField.Value = '';
            app.DescriptionEditField.Value = '';
            app.DigitalIdentifierDropDown.Value = 'Select type';
            app.DigitalIdentifierEditField.Value = '';
            app.ManufacturerEditField.Value = '';
            app.CompleteCheckBox.Value = 0;
            app.ExternalDiameterEditField.Value = '';
            app.ExternalDiameterUnitListBoxLabel.Value = app.ExternalDiameterUnitListBoxLabel.Items{1};
            app.InternalDiameterEditField.Value = '';
            app.InternalDiameterUnitListBox.Value = app.InternalDiameterUnitListBox.Items{1};
            app.MaterialEditField.Value = '';
            app.FinishState = "";
        end

        function waitfor(app)
            app.IsStandalone = false;
            uiwait(app.UIFigure)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartUp(app)
            ndi.database.metadata_app.fun.loadInstancesToListBox(app.InternalDiameterUnitListBox, 'UnitOfMeasurement', {'meter'});
            ndi.database.metadata_app.fun.loadInstancesToListBox(app.ExternalDiameterUnitListBox, 'UnitOfMeasurement', {'meter'});
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            pipette = app.getProbeDetails();
            app.FinishState = "Cancel";
            app.UIFigureCloseRequest()
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            app.FinishState = "Save";
            app.UIFigureCloseRequest()
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.IsStandalone
                delete(app)
            else
                if app.FinishState == ""
                    app.FinishState = uiconfirm(app.UIFigure, 'Do you want to save the new electrode array?', 'Save changes?', 'Options', {'Save', 'Cancel'} );
                end
                uiresume(app.UIFigure)
            end
        end

        % Value changed function: ManufacturerEditField
        function ManufacturerValueChanged(app, event)
            value = app.ManufacturerEditField.Value;
            app.openManufacturerForm(value);
        end

        % Value changed function: InternalDiameterUnitListBox
        function InternalDiameterUnitListBoxValueChanged(app, event)
            value = app.InternalDiameterUnitListBox.Value;
            if ~(app.internalDiameterEntered())
                app.InternalDiameterUnitListBox.Value = app.InternalDiameterUnitListBox.Items{1};
                uialert(app.UIFigure, 'Please enter value for internal diameter', 'Empty internal diameter');
            end
        end

        % Value changed function: ExternalDiameterUnitListBox
        function ExternalDiameterUnitListBoxValueChanged(app, event)
            value = app.ExternalDiameterUnitListBox.Value;
            if ~(app.externalDiameterEntered())
                app.ExternalDiameterUnitListBox.Value = app.ExternalDiameterUnitListBox.Items{1};
                uialert(app.UIFigure, 'Please enter value for external diameter', 'Empty external diameter');
            end
        end

        % Value changed function: DigitalIdentifierEditField
        function DigitalIdentifierValueChanged(app, event)
            value = app.DigitalIdentifierEditField.Value;
            if ~(app.digitalIdentifierSelected())
                app.DigitalIdentifierEditField.Value = '';
                uialert(app.UIFigure, 'Please select the digital identifier type', 'Empty Digital Identifier Type');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PIPETTELabel = uilabel(app.UIFigure);
            app.PIPETTELabel.Text = 'PIPETTE';
            app.InternalDiameterEditFieldLabel = uilabel(app.UIFigure);
            app.InternalDiameterEditFieldLabel.Text = 'Internal Diameter';
            app.InternalDiameterEditField = uieditfield(app.UIFigure, 'text');
            app.InternalDiameterEditField.Tooltip = {'Optional'};
            app.ExternalDiameterEditFieldLabel = uilabel(app.UIFigure);
            app.ExternalDiameterEditFieldLabel.Text = 'External Diameter';
            app.ExternalDiameterEditField = uieditfield(app.UIFigure, 'text');
            app.ExternalDiameterEditField.Tooltip = {'Optional'};
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NameEditField.Editable = 'off';
            app.DescriptionEditFieldLabel = uilabel(app.UIFigure);
            app.DescriptionEditFieldLabel.Text = 'Description';
            app.DescriptionEditField = uieditfield(app.UIFigure, 'text');
            app.DescriptionEditField.Placeholder = 'Catalog No.';
            app.DigitalIdentifierEditFieldLabel = uilabel(app.UIFigure);
            app.DigitalIdentifierEditFieldLabel.Text = 'Digital Identifier';
            app.DigitalIdentifierEditField = uieditfield(app.UIFigure, 'text');
            app.DigitalIdentifierEditField.ValueChangedFcn = createCallbackFcn(app, @DigitalIdentifierValueChanged, true);
            app.DigitalIdentifierEditField.Placeholder = 'DOI/RRID';
            app.ManufaturerEditFieldLabel = uilabel(app.UIFigure);
            app.ManufaturerEditFieldLabel.Text = 'Manufaturer';
            app.ManufacturerEditField = uieditfield(app.UIFigure, 'text');
            app.ManufacturerEditField.ValueChangedFcn = createCallbackFcn(app, @ManufacturerValueChanged, true);
            app.MaterialEditFieldLabel = uilabel(app.UIFigure);
            app.MaterialEditFieldLabel.Text = 'Material';
            app.MaterialEditField = uieditfield(app.UIFigure, 'text');
            app.CreateButton = uibutton(app.UIFigure, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CompleteCheckBox = uicheckbox(app.UIFigure);
            app.CompleteCheckBox.Text = '';
            app.NoofElectrodesEditFieldLabel = uilabel(app.UIFigure);
            app.NoofElectrodesEditFieldLabel.Text = 'Complete';
            app.ExternalDiameterUnitListBoxLabel = uilabel(app.UIFigure);
            app.ExternalDiameterUnitListBoxLabel.Text = 'Unit';
            app.ExternalDiameterUnitListBox = uilistbox(app.UIFigure);
            app.ExternalDiameterUnitListBox.Items = {'load'};
            app.ExternalDiameterUnitListBox.ValueChangedFcn = createCallbackFcn(app, @ExternalDiameterUnitListBoxValueChanged, true);
            app.ExternalDiameterUnitListBox.Value = 'load';
            app.InternalDiameterUnitListBox = uilistbox(app.UIFigure);
            app.InternalDiameterUnitListBox.Items = {'load'};
            app.InternalDiameterUnitListBox.ValueChangedFcn = createCallbackFcn(app, @InternalDiameterUnitListBoxValueChanged, true);
            app.InternalDiameterUnitListBox.Value = 'load';
            app.InternalDiameterUnitListBoxLabel = uilabel(app.UIFigure);
            app.InternalDiameterUnitListBoxLabel.Text = 'Unit';
            app.DigitalIdentifierDropDown = uidropdown(app.UIFigure);
            app.DigitalIdentifierDropDown.Items = {'Select type', 'ROI', 'RRID'};
            app.DigitalIdentifierDropDown.Placeholder = 'Select type';
            app.DigitalIdentifierDropDown.Value = 'Select type';
            app.DigitalIdentifierDropDownLabel = uilabel(app.UIFigure);
            app.DigitalIdentifierDropDownLabel.Text = 'Digital Identifier';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PipetteForm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end